classdef DAQx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ResetMenu                       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        AcquisitionpropertiesTab        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Button3                         matlab.ui.control.Button
        RemoveDAQobjectButton           matlab.ui.control.Button
        SaveDAQobjectButton             matlab.ui.control.Button
        RemoveselectedchannelButton     matlab.ui.control.Button
        DAQObjectTree                   matlab.ui.container.CheckBoxTree
        DAQobjecttreeLabel              matlab.ui.control.Label
        Button2                         matlab.ui.control.Button
        AddchannelsButton               matlab.ui.control.Button
        AvailablechannelsLabel          matlab.ui.control.Label
        UITableAddChannels              matlab.ui.control.Table
        AddDAQobjectfortheselectedvendorButton  matlab.ui.control.Button
        ScanvendorsButton               matlab.ui.control.Button
        DAQdevicetreeLabel              matlab.ui.control.Label
        DAQDeviceTree                   matlab.ui.container.CheckBoxTree
        DatabaseTab                     matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        RemoveselecteddataButton        matlab.ui.control.Button
        ExportdataButton                matlab.ui.control.Button
        StufffordatamanipulationexportcalculationetcLabel  matlab.ui.control.Label
        DatabaseTree                    matlab.ui.container.CheckBoxTree
        AcquisitionNode                 matlab.ui.container.TreeNode
        RightPanel                      matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        ObjectpropertiesTab             matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        PropertiesfortheselecteddeviceLabel  matlab.ui.control.Label
        UITableSelectedObjectProperties  matlab.ui.control.Table
        MeasurementTab                  matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        StopacquisitionButton           matlab.ui.control.Button
        WriteoutputButton               matlab.ui.control.Button
        PlotoptionsLabel                matlab.ui.control.Label
        LoaddataPressthebuttonafterfinishedmeasurementButton  matlab.ui.control.Button
        AcquisitiontimesEditField       matlab.ui.control.NumericEditField
        AcquisitiontimesEditFieldLabel  matlab.ui.control.Label
        ReadinputButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % Description
        DAQObject
        DAQDevice % Description
        Vendor
    end
    
    methods (Access = public)
        
        function DetectDAQDevices(app)
            %% Detecting drivers and devices
            app.Vendor = daqvendorlist; % daq.getVendors;
            if isempty(app.Vendor)
                DisplayUIAlert(app, "Error! DAQmx drivers have not been detected or installed.", ...
                    "Please install drivers for your devices");
                return;
            else
                detectedDriversTable = table2cell(app.Vendor);
                fprintf("%s\n", detectedDriversTable{:});
            end
            
            app.DAQDevice = daqlist; % daq.getDevices;
            if isempty(app.DAQDevice)
                DisplayUIAlert(app, "Error! No DAQ devices detected. Please connect the acquisition devices.", ...
                    "Please verify the connection with acquisition device");
                return;
            else
                deviceNames = app.DAQDevice.Model;
                fprintf("DAQ devices detected:\n");
                fprintf("%s\n", deviceNames)
            end
            
            %% Setting up the DAQ device tree according to detected devices
            vendorNode = gobjects(1, size(app.Vendor, 1));
            deviceNode = gobjects(1, size(app.DAQDevice, 1));
            for i = 1 : size(app.Vendor, 1)
                vendorNode(i) = uitreenode(app.DAQDeviceTree, "Text", app.Vendor.ID(i), ...
                        "NodeData", app.Vendor.ID(i));
                for j = 1 : size(app.DAQDevice, 1)
                    deviceNode(j) = uitreenode(vendorNode(i), ...
                        "Text", deviceNames(j) + " " + "(" + app.DAQDevice.DeviceInfo(j).ID + ")", ...
                        "NodeData", app.DAQDevice.DeviceInfo(j).ID);
                    numSubsystems = numel(app.DAQDevice(j,:).DeviceInfo.Subsystems);
                    for k = 1 : numSubsystems
                        uitreenode(deviceNode(j), "Text", app.DAQDevice.DeviceInfo(j).Subsystems(k).SubsystemType, ...
                            "NodeData", app.DAQDevice.DeviceInfo(j).Subsystems(k).SubsystemType);
                    end
                end
            end
        end
        
        function UITableChildCellEdit(app, event)
            % To do: generalize
            indices = app.UITableSelectedObjectProperties.Selection;
            targetObject = app.UITableSelectedObjectProperties.Data{indices(1), 2}{1};
            sourceField = event.Source.Data{indices(1), "fields"}{1};
            fig = event.Source.Parent;
            try
                targetObject.(sourceField) = event.NewData;
            catch exc
                uialert(fig, {exc.message, '*** Reverting to previous values ***'},...
                    exc.identifier, "Modal", true);
            end
        end
        
        function PlotDataAvailable(app, src, event)
            [data, timestamps, ~] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");
%              [app.DAQObject.Channels.ID + " " + app.DAQObject.Channels.MeasurementType]
            
            persistent hPlot;
            if all(isempty(hPlot)) || all(~isvalid(hPlot))
                hPlot = plot(app.UIAxes, timestamps, data);     
                hold(app.UIAxes, "on");                 
                legend(app.UIAxes, hPlot, append({app.DAQObject.Channels.Name}, ' ', {app.DAQObject.Channels.MeasurementType}), ...
                    'Interpreter', 'none');
            else
                
                % Assemble the data
                numChannels = size(data, 2);       
                for i = 1 : numChannels
                    xData(i, :) = [hPlot(i).XData, timestamps']; % Optimize
                    yData(i, :) = [hPlot(i).YData, data(:, i)']; % Optimize
                end
                
                set(hPlot, {'Xdata'}, num2cell(xData, 2), {'YData'}, num2cell(yData, 2)); % why does this work?! 
            end
        end
        
        function obj = GetObjectFromNode(app, textID)
            % Identify if selected node is vendor or channel
            if strcmpi(textID, app.DAQObject.Vendor.ID)
                app.UITableSelectedObjectProperties.UserData = 'vendor';
                obj = app.DAQObject;
            elseif (any(cellfun(@(x)strcmpi(x, textID), {app.DAQObject.Channels.Name})))
                app.UITableSelectedObjectProperties.UserData = 'channel';
                idx = cellfun(@(x)strcmpi(x, textID), {app.DAQObject.Channels.Name});
                obj = app.DAQObject.Channels(idx);
            else
                error('wrong selection');
            end
        end
        
        function UpdateDAQObjectTree(app)
            % Updating channels, assuming only one daq object % To do:
            % generalise
            daqObjectNode = app.DAQObjectTree.Children(1);
            delete(daqObjectNode.Children);
            numChannels = numel(app.DAQObject.Channels);

            for i = 1 : numChannels
                uitreenode(daqObjectNode, "Text", app.DAQObject.Channels(i).Name);
            end
        end
        
        function InitialiseDatabaseTree(app)
            % Adds Acquisition node only
            delete(app.DatabaseTree.Children);
            uitreenode(app.DatabaseTree, "Text", "Acquisition");
        end
        
        function DisplayUIAlert(app, message, title)
            fig = app.UIFigure;
            uialert(fig, message, title, "Modal", true);
        end
    end
    
    methods (Access = private)
        
        function objectTable = GetObjectProperties(~, object)
            % Get fields, their values and put them in table
            if isstruct(object) || isobject(object) && ~istable(object) && ~strcmpi(class(object), 'daq.Channel')
                fields = fieldnames(object); 
                if isempty(object)
                    % To do: handle better
                    objectTable = table();
                    return;
                end
                rmIdx = cellfun(@(x)strcmpi(x, 'ADCTimingMode'), fields); % 'ADCTimingMode' property bugs out
                fields (rmIdx) = [];
                propVals = cell(length(fields), 1);
                for ii = 1:length(fields)
                    propVals{ii,1} = object.(fields{ii,1});
                end
                objectTable = table(fields, propVals);
            elseif strcmpi(class(object), 'daq.Channel')
                objectTable = table(object);
            elseif iscell(object)
                objectTable = table(object);
            elseif istable(object)
                objectTable = object;
% % %             elseif numeric array with more than 1 element % To do:
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            
        end

        % Selection changed function: DAQDeviceTree
        function DAQDeviceTreeSelectionChanged(app, event)
            %% Identify the selection: Vendor, Device or Subsystems
            if ~any(isvalid(app.DAQDevice.DeviceInfo))
                DisplayUIAlert(app, "DAQ device is not valid. Remove and add DAQ object for selected vendor or restart the app and repeat the procedure", ...
                    "Error");
                return;
            end

            selectedNode = app.DAQDeviceTree.SelectedNodes;
            textID = selectedNode.NodeData; % textID is a vendorID char array, or a deviceID or a subsystemtype
            subsystemTypes = ["AnalogInput", "AnalogOutput", "DigitalIO", "CounterInput", "CounterOutput", "AudioOutput"];

            if any(eq(app.DAQDevice.VendorID, textID)) % Vendors
                idx = eq(app.DAQDevice.VendorID, textID);
                propObject = app.DAQDevice(idx, :);
                app.UITableAddChannels.Data = [];
            elseif any(eq(app.DAQDevice.DeviceID, textID)) % Devices 
                idx = eq(app.DAQDevice.DeviceID, textID);
                if sum(idx) > 1 % Can only be one found
                    error('??');
                end
                propObject = app.DAQDevice(idx, :).DeviceInfo;
                app.UITableAddChannels.Data = [];
            elseif any(eq(subsystemTypes, string(textID))) % Subsystems % Merge into else or get all possible ss types
                % Identify the device first by the parent node
                deviceID = app.DAQDeviceTree.SelectedNodes.Parent.NodeData;
                if(any(eq(app.DAQDevice.DeviceID, deviceID)))
                    deviceIdx = eq(app.DAQDevice.DeviceID, string(deviceID));
                end
                if sum(deviceIdx) > 1 % Can only be one found
                    error('??');
                end
                
                % Identify the selected subsystem
%                 if (any(eq(string({app.DAQDevice(deviceIdx, :).DeviceInfo.Subsystems.SubsystemType}), subsystemTypes)))
                    subsystemIdx = eq(string({app.DAQDevice(deviceIdx, :).DeviceInfo.Subsystems.SubsystemType}), textID);
%                 end
                
                % Prepare the output for the channels table
                propObject = app.DAQDevice(deviceIdx, :).DeviceInfo.Subsystems(subsystemIdx);
                channelType = {'Please select', 'input', 'output', 'bidirectional'};
                vendorID = {'Please select', char(app.DAQDevice(deviceIdx,:).VendorID)};
                deviceID = {'Please select', char(app.DAQDevice(deviceIdx,:).DeviceID)};
                deviceChanelNames = app.DAQDevice(deviceIdx, :).DeviceInfo.Subsystems(subsystemIdx).ChannelNames;
                if iscolumn(deviceChanelNames)
                    deviceChanelNames = deviceChanelNames';
                end
                chanNames = ['Please select', deviceChanelNames];
                measTypes = ['Please select', app.DAQDevice(deviceIdx, :).DeviceInfo.Subsystems(subsystemIdx).MeasurementTypesAvailable];
                
                numChannelsAvail = app.DAQDevice(deviceIdx, :).DeviceInfo.Subsystems(subsystemIdx).NumberOfChannelsAvailable;
                rowData = [false, repmat({'Please select'}, [1, 5])];
                app.UITableAddChannels.Data = repmat(rowData, [numChannelsAvail, 1]);
                app.UITableAddChannels.ColumnFormat = {[], channelType, vendorID, deviceID, chanNames, measTypes};
            else
                % "AudioOutput" To do:
                error('Add this subsystem');
            end
            
            % Get properties of the object and display it in the UITable  
            objectData = GetObjectProperties(app, propObject);
            app.UITableSelectedObjectProperties.Data = objectData;
              
        end

        % Button pushed function: AddDAQobjectfortheselectedvendorButton
        function AddDAQobjectfortheselectedvendorButtonPushed(app, event)
            if ~isempty(app.DAQDeviceTree.SelectedNodes)
                selectionID = app.DAQDeviceTree.SelectedNodes.Text;
            else % Nothing selected
                DisplayUIAlert(app, "Unable to determine for which vendor to create the DAQ object. ..." + ...
                    "Please select a node in the DAQ objects tree to add appropriate vendor", "Missing selection");
                return;
            end
                obj = [];
            if ~any(eq(selectionID, app.DAQDevice.VendorID)) % Selection is one of the vendor child nodes, find vendor node % To do: cleaner version
                while(1)
                    if isempty(obj)
                        obj = app.DAQDeviceTree.SelectedNodes;
                    end
                    objID = obj.Text;
                    if any(eq(objID, app.DAQDevice.VendorID))
                        vendorID = objID;
                        break;
                    else
                        obj = obj.Parent;
                    end
                end
            else
                vendorID = selectionID;
            end
                
            % Adding vendor and functions
            app.DAQObject = daq(vendorID);
            app.DAQObject.ScansAvailableFcn = @(src, event) PlotDataAvailable (app, src, event); % Function that plots the collected data
            
            % DAQ object node
            currentTime = string(datetime('now'));
            uitreenode(app.DAQObjectTree, "Text", vendorID + " " + "(" + currentTime + ")");
        end

        % Button pushed function: ScanvendorsButton
        function ScanvendorsButtonPushed(app, event)
            daqreset;
            delete(app.DAQDeviceTree.Children);
            DetectDAQDevices(app);
        end

        % Button pushed function: AddchannelsButton
        function AddchannelsButtonPushed(app, event)
            %% Figure out a way to uniquely identify channels, devices, vendors To do
            % Check if DAQ Object exists 
            % Find vendor ID
%             vendorIDs = app.DAQDevice.VendorID;
%             nodeData = app.DAQDeviceTree.SelectedNodes.NodeData;
            if isempty (app.DAQObject)
                uialert(app.UIFigure, 'Missing DAQ object, press button to create it for specified vendor', "Error", "Modal", true);
                return;
            else
                channelsToAdd = [app.UITableAddChannels.Data{:, 1}];
                for i = 1 : numel(channelsToAdd)
                    if channelsToAdd(i)
                        deviceID = app.UITableAddChannels.Data{i, 4};
                        channelID = app.UITableAddChannels.Data{i, 5};
                        measType = app.UITableAddChannels.Data{i, 6};
                        channelType = app.UITableAddChannels.Data{i, 2}; % input, output or bidirectional
                        switch channelType
                            case 'input'
                                addinput(app.DAQObject, deviceID, channelID, measType);
                            case 'output'
                                addoutput(app.DAQObject, deviceID, channelID, measType);
                            case 'bidirectional'
                                % To do: handle the case for bidirectional
                            otherwise
                        end
                        chanNodeData = deviceID + "_" + channelID;
                        uitreenode(app.DAQObjectTree.Children(1), 'Text', chanNodeData); % To do: add to specific DAQ object
                    end
                end
            end
            UpdateDAQObjectTree(app);
        end

        % Cell edit callback: UITableSelectedObjectProperties
        function UITableSelectedObjectPropertiesCellEdit(app, event)
            %% Function to handle the edits to the object properties UITable
                % To do: needs more work, in general it's decent. Figure
                % out better way to handle exceptions with try-catch blocks
                % - Handle expected values in a better weay
                % - Handle fields that contain arrays because those can't be displayed
                % - Handle fields that contain function handles
            indices = event.Indices;
            value = event.NewData;
            textID = app.DAQObjectTree.SelectedNodes.Text;
            chanIdx = cellfun(@(x) strcmpi(textID, x), {app.DAQObject.Channels.Name});
            field = app.UITableSelectedObjectProperties.Data{indices(1), "fields"}{1};
            
            try
                if strcmpi(app.UITableSelectedObjectProperties.UserData, 'channel')
                    app.DAQObject.Channels(chanIdx).(field) = value;
                elseif strcmpi(app.UITableSelectedObjectProperties.UserData, 'vendor')
                    app.DAQObject.(field) = value;
                end
            catch exc
                errorFlag = true;
                switch exc.identifier
                    case 'daq:general:invalidMemberOfEnum' % Correct variable type, no such option
                        event.Source.Data{indices(1), indices(2)}{1} = event.PreviousData;
                    case 'nidaq:ni:channelDoesNotSupport' 
                        event.Source.Data{indices(1), indices(2)}{1} = event.PreviousData;
                    case 'nidaq:ni:invalidMicSensitivity'
                        if ischar(value)
                            event.Source.Data{indices(1), indices(2)}{1} = str2double(value);
                            app.DAQObject.Channels(chanIdx).(field) = str2double(value);
                            errorFlag = false;
                        end
                    case 'nidaq:ni:invalidAccelerometerSensitivity'
                         if ischar(value)
                            event.Source.Data{indices(1), indices(2)}{1} = str2double(value);
                            app.DAQObject.Channels(chanIdx).(field) = str2double(value);
                            errorFlag = false;
                        end
                    case 'nidaq:ni:invalidMicSPL' % nidaq:ni:SPLExceedsMaximum
                        if ~isnumeric(value)
                            value = 1;
                            errorFlag = false;
                        end
                        app.DAQObject.Channels(chanIdx).(field) = value;
                        app.UITableSelectedObjectProperties.Data{indices(1), indices(2)}{1} = value;
                        uialert(app.UIFigure, {exc.message, '*** Known issue, to be fixed: Setting SPL to 1, redo the changes ***'},...
                            exc.identifier, "Modal", true);
                    case 'nidaq:ni:SPLExceedsMaximum'
                        msgContents = strsplit(exc.message, {' ', '.'});
                        value = str2double(msgContents{end-1});
                        app.DAQObject.Channels(chanIdx).(field) = value;
                        app.UITableSelectedObjectProperties.Data{indices(1), indices(2)}{1} = value;

                        uialert(app.UIFigure, {exc.message, '*** Setting to maximum SPL ***'},...
                            exc.identifier, "Modal", true);
                        errorFlag = false;
                end

                if errorFlag
                    event.Source.Data{indices(1), indices(2)}{1} = event.PreviousData;
                    uialert(app.UIFigure, {exc.message, '*** Reverting to previous values ***'},...
                        exc.identifier, "Modal", true);
                end
            end
        end

        % Cell selection callback: UITableSelectedObjectProperties
        function UITableSelectedObjectPropertiesCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2) == 1 % Activate only if property values cannot be inspected
                    object = event.Source.Data{indices(1), 2}{1};
                    if iscell(object) || isstruct(object) || isobject (object)
                        objectTable = GetObjectProperties(app, object);
                        if isempty(objectTable)
                            return;
                        end
                        % 
                        % Create new uifigure with uitable
                        uiFig = uifigure();
                        UITable = uitable(uiFig, ...
                            "ColumnEditable", [false, true], ...
                            "CellEditCallback", createCallbackFcn(app, @UITableChildCellEdit, true), ...
                            "ColumnEditable", true);
                        UITable.Data = objectTable;
                    end
                end
            end
        end

        % Selection changed function: DAQObjectTree
        function DAQObjectTreeSelectionChanged(app, event)
            selectedNodes = app.DAQObjectTree.SelectedNodes;
            selectedText = strsplit(selectedNodes.Text, ' ('); % Remove time from comparison
            selectedText = selectedText{1};
            % Identify if selected node is vendor or channel
            if strcmpi(selectedText, app.DAQObject.Vendor.ID)
                app.UITableSelectedObjectProperties.UserData = 'vendor';
                propObject = app.DAQObject;
            elseif (any(cellfun(@(x)strcmpi(x, selectedText), {app.DAQObject.Channels.Name})))
                app.UITableSelectedObjectProperties.UserData = 'channel';
                idx = cellfun(@(x)strcmpi(x, selectedText), {app.DAQObject.Channels.Name});
                propObject = app.DAQObject.Channels(idx);
            else
                error('wrong selection');
            end
            
            structTableData = GetObjectProperties(app, propObject);
            app.UITableSelectedObjectProperties.Data = structTableData;
        end

        % Button pushed function: RemoveDAQobjectButton
        function RemoveDAQobjectButtonPushed(app, event)
            % Function to remove DAQ Objects
            daqreset;
            app.DAQObject = [];
            delete(app.DAQObjectTree.Children);
            InitialiseDatabaseTree(app);
        end

        % Button pushed function: RemoveselectedchannelButton
        function RemoveselectedchannelButtonPushed(app, event)
            % Function to remove selected channel
            if ~isempty(app.DAQDeviceTree.SelectedNodes)
                channelName = app.DAQObjectTree.SelectedNodes.Text;
            else
                return;
            end
            channelIdx = find(cellfun(@(x) strcmpi(x, channelName), {app.DAQObject.Channels.Name}), 1);
            if any(channelIdx)
                removechannel(app.DAQObject, channelIdx);
            else
                return;
            end
            UpdateDAQObjectTree(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {768, 768};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {527, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ReadinputButton
        function ReadinputButtonPushed(app, event)
            cla(app.UIAxes);
            currentTime = string(datetime('now'));
            vendorID = app.DAQObject.Vendor.ID;
            numChannels = numel(app.DAQObject.Channels);
            try
                start(app.DAQObject, "Duration", app.AcquisitiontimesEditField.Value);
                % Create uitreenodes for each measurement and for each
                % signal acquired
                idx = cellfun(@(x) strcmpi(x, 'Acquisition'), {app.DatabaseTree.Children.Text});
                currentAcqNode = uitreenode(app.DatabaseTree.Children(idx), "NodeData", "Acquisition", "Text", vendorID + currentTime);
                app.DatabaseTree.SelectedNodes = currentAcqNode;
                for i = 1 : numChannels
                    uitreenode(currentAcqNode, "NodeData", "Channel", "Text", app.DAQObject.Channels(i).Name);
                end
            catch exc
                DisplayUIAlert(app, exc.identifier, exc.message);
            end
        end

        % Button pushed function: 
        % LoaddataPressthebuttonafterfinishedmeasurementButton
        function LoaddataPressthebuttonafterfinishedmeasurementButtonPushed(app, event)
            % Function to save the data in the Node.UserData
            if ~app.DAQObject.Running
                numChannels = numel(app.UIAxes.Children);
                data = struct('name', '', 'xData', [], 'yData', []);
                for i = 1 : numChannels
                    data(i).name = app.UIAxes.Children(i).DisplayName;
                    data(i).xData = app.UIAxes.Children(i).XData;
                    data(i).yData = app.UIAxes.Children(i).YData;
                end
                app.DatabaseTree.SelectedNodes.UserData = data;
            else
                return;
            end     
        end

        % Selection changed function: DatabaseTree
        function DatabaseTreeSelectionChanged(app, event)
            % Plot the data from UserData property of the node
            selectedNodes = app.DatabaseTree.SelectedNodes;
            if ~isempty(selectedNodes) && ~isempty(selectedNodes.UserData) && strcmpi(selectedNodes.NodeData, "Acquisition")
                cla(app.UIAxes);
                plot(app.UIAxes, cell2mat({selectedNodes.UserData.xData}')', cell2mat({selectedNodes.UserData.yData}')')
                legend(app.UIAxes, {selectedNodes.UserData.name});
            elseif ~isempty(selectedNodes) && ~isempty(selectedNodes.UserData) && strcmpi(selectedNodes.NodeData, "Channel")
                % To do: make other signals transparent
            else
                if ~strcmpi(selectedNodes.NodeData, "Acquisition") || ~strcmpi(selectedNodes.NodeData, "Channel")
                    return;
                end
                DisplayUIAlert(app, "Node data is empty", "");
            end
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            % To do: better way, handle selections
            defaultFileName = string(datetime('now'), 'yyyy-MM-dd HH_mm_ss') + ".mat";
            [fileName, filePath]= uiputfile('.mat', 'Set file name for export', defaultFileName);
            if fileName
                fullFilePath = fullfile(filePath, fileName);
                data = {app.DatabaseTree.Children(1).Children.UserData};
                save(fullFilePath, 'data');
            end
        end

        % Button pushed function: StopacquisitionButton
        function StopacquisitionButtonPushed(app, event)
            stop(app.DAQObject);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ResetMenu = uimenu(app.Menu);
            app.ResetMenu.Text = 'Reset';
            app.HelpMenu = uimenu(app.Menu);
            app.HelpMenu.Text = 'Help';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout6);
            app.AcquisitionpropertiesTab = uitab(app.TabGroup);
            app.AcquisitionpropertiesTab.Title = 'Acquisition properties';
            app.GridLayout2 = uigridlayout(app.AcquisitionpropertiesTab);
            app.DAQDeviceTree = uitree(app.GridLayout2, 'checkbox');
            app.DAQDeviceTree.SelectionChangedFcn = createCallbackFcn(app, @DAQDeviceTreeSelectionChanged, true);
            app.DAQdevicetreeLabel = uilabel(app.GridLayout2);
            app.DAQdevicetreeLabel.Tooltip = {'Detected vendors and devices'};
            app.DAQdevicetreeLabel.Text = 'DAQ device tree';
            app.ScanvendorsButton = uibutton(app.GridLayout2, 'push');
            app.ScanvendorsButton.ButtonPushedFcn = createCallbackFcn(app, @ScanvendorsButtonPushed, true);
            app.ScanvendorsButton.Text = 'Scan vendors';
            app.AddDAQobjectfortheselectedvendorButton = uibutton(app.GridLayout2, 'push');
            app.AddDAQobjectfortheselectedvendorButton.ButtonPushedFcn = createCallbackFcn(app, @AddDAQobjectfortheselectedvendorButtonPushed, true);
            app.AddDAQobjectfortheselectedvendorButton.Tooltip = {'Create DataAcquisition object for selected vendor'};
            app.AddDAQobjectfortheselectedvendorButton.Text = 'Add DAQ object for the selected vendor';
            app.UITableAddChannels = uitable(app.GridLayout2);
            app.UITableAddChannels.ColumnName = {'Use'; 'Type'; 'DAQDevice'; 'DeviceID'; 'Channel'; 'MeasurementType'};
            app.UITableAddChannels.RowName = {};
            app.UITableAddChannels.ColumnEditable = true;
            app.AvailablechannelsLabel = uilabel(app.GridLayout2);
            app.AvailablechannelsLabel.Tooltip = {'Available channels for the specified DAQ device (does not checks if the channel has been added already)'};
            app.AvailablechannelsLabel.Text = 'Available channels';
            app.AddchannelsButton = uibutton(app.GridLayout2, 'push');
            app.AddchannelsButton.ButtonPushedFcn = createCallbackFcn(app, @AddchannelsButtonPushed, true);
            app.AddchannelsButton.Tooltip = {'Add channels below to the DataAcquisition object'};
            app.AddchannelsButton.Text = 'Add channels';
            app.Button2 = uibutton(app.GridLayout2, 'push');
            app.Button2.Text = 'Button2';
            app.DAQobjecttreeLabel = uilabel(app.GridLayout2);
            app.DAQobjecttreeLabel.Tooltip = {'Contains the list of channels'};
            app.DAQobjecttreeLabel.Text = 'DAQ object tree';
            app.DAQObjectTree = uitree(app.GridLayout2, 'checkbox');
            app.DAQObjectTree.SelectionChangedFcn = createCallbackFcn(app, @DAQObjectTreeSelectionChanged, true);
            app.RemoveselectedchannelButton = uibutton(app.GridLayout2, 'push');
            app.RemoveselectedchannelButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedchannelButtonPushed, true);
            app.RemoveselectedchannelButton.Text = 'Remove selected channel';
            app.SaveDAQobjectButton = uibutton(app.GridLayout2, 'push');
            app.SaveDAQobjectButton.Tooltip = {'DAQ objects cannot be saved, however, commands to add the devices and channels can be saved and ran'};
            app.SaveDAQobjectButton.Text = 'Save DAQ object';
            app.RemoveDAQobjectButton = uibutton(app.GridLayout2, 'push');
            app.RemoveDAQobjectButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDAQobjectButtonPushed, true);
            app.RemoveDAQobjectButton.Text = 'Remove DAQ object';
            app.Button3 = uibutton(app.GridLayout2, 'push');
            app.Button3.Text = 'Button3';
            app.DatabaseTab = uitab(app.TabGroup);
            app.DatabaseTab.Title = 'Database';
            app.GridLayout7 = uigridlayout(app.DatabaseTab);
            app.DatabaseTree = uitree(app.GridLayout7, 'checkbox');
            app.DatabaseTree.SelectionChangedFcn = createCallbackFcn(app, @DatabaseTreeSelectionChanged, true);
            app.AcquisitionNode = uitreenode(app.DatabaseTree);
            app.AcquisitionNode.Text = 'Acquisition';
            app.StufffordatamanipulationexportcalculationetcLabel = uilabel(app.GridLayout7);
            app.StufffordatamanipulationexportcalculationetcLabel.Text = 'Stuff for data manipulation, export, calculation etc.';
            app.ExportdataButton = uibutton(app.GridLayout7, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Text = 'Export data';
            app.RemoveselecteddataButton = uibutton(app.GridLayout7, 'push');
            app.RemoveselecteddataButton.Text = 'Remove selected data';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.RightPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout5);
            app.ObjectpropertiesTab = uitab(app.TabGroup2);
            app.ObjectpropertiesTab.Title = 'Object properties';
            app.GridLayout4 = uigridlayout(app.ObjectpropertiesTab);
            app.UITableSelectedObjectProperties = uitable(app.GridLayout4);
            app.UITableSelectedObjectProperties.ColumnName = {'Property'; 'Value'};
            app.UITableSelectedObjectProperties.RowName = {};
            app.UITableSelectedObjectProperties.ColumnEditable = [false true];
            app.UITableSelectedObjectProperties.CellEditCallback = createCallbackFcn(app, @UITableSelectedObjectPropertiesCellEdit, true);
            app.UITableSelectedObjectProperties.CellSelectionCallback = createCallbackFcn(app, @UITableSelectedObjectPropertiesCellSelection, true);
            app.PropertiesfortheselecteddeviceLabel = uilabel(app.GridLayout4);
            app.PropertiesfortheselecteddeviceLabel.Text = 'Properties for the selection from Tree components in Acquisition properties tab';
            app.MeasurementTab = uitab(app.TabGroup2);
            app.MeasurementTab.Title = 'Measurement';
            app.GridLayout3 = uigridlayout(app.MeasurementTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ReadinputButton = uibutton(app.GridLayout3, 'push');
            app.ReadinputButton.ButtonPushedFcn = createCallbackFcn(app, @ReadinputButtonPushed, true);
            app.ReadinputButton.Text = 'Read (input)';
            app.AcquisitiontimesEditFieldLabel = uilabel(app.GridLayout3);
            app.AcquisitiontimesEditFieldLabel.Text = 'Acquisition time [s]';
            app.AcquisitiontimesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AcquisitiontimesEditField.Value = 5;
            app.LoaddataPressthebuttonafterfinishedmeasurementButton = uibutton(app.GridLayout3, 'push');
            app.LoaddataPressthebuttonafterfinishedmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataPressthebuttonafterfinishedmeasurementButtonPushed, true);
            app.LoaddataPressthebuttonafterfinishedmeasurementButton.Tooltip = {'To do: looking how to do this without button'};
            app.LoaddataPressthebuttonafterfinishedmeasurementButton.Text = {'Load data into selected tree node'; 'Press the button after finished measurement.'};
            app.PlotoptionsLabel = uilabel(app.GridLayout3);
            app.PlotoptionsLabel.Text = 'Plot options';
            app.WriteoutputButton = uibutton(app.GridLayout3, 'push');
            app.WriteoutputButton.Tooltip = {'To do:'};
            app.WriteoutputButton.Text = 'Write (output)';
            app.StopacquisitionButton = uibutton(app.GridLayout3, 'push');
            app.StopacquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StopacquisitionButtonPushed, true);
            app.StopacquisitionButton.Text = 'Stop acquisition';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQx

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end